classdef TelemetriaBajaSae < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TelemetriaBajaSAEUIFigure    matlab.ui.Figure
        StatusLamp                   matlab.ui.control.Lamp
        LampLabel                    matlab.ui.control.Label
        ReproducionDropDown          matlab.ui.control.DropDown
        ReproducionDropDownLabel     matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        MotorTab                     matlab.ui.container.Tab
        SaveDatatoCSVButton          matlab.ui.control.Button
        ConnectSwitch                matlab.ui.control.Switch
        COMLabel                     matlab.ui.control.Label
        COMEditField                 matlab.ui.control.NumericEditField
        VelocidadLabel               matlab.ui.control.Label
        VelocidadSpinner             matlab.ui.control.Spinner
        TimeLabel                    matlab.ui.control.Label
        PlayButton                   matlab.ui.control.Button
        rpmGauge                     matlab.ui.control.SemicircularGauge
        rpmx1000GaugeLabel           matlab.ui.control.Label
        TiempoaMostrarLabel          matlab.ui.control.Label
        TiempoaMostrarSlider         matlab.ui.control.RangeSlider
        tempCGauge                   matlab.ui.control.LinearGauge
        tempCGaugeLabel              matlab.ui.control.Label
        UIAxesRpmVsTemp              matlab.ui.control.UIAxes
        UIAxesTVsRpm                 matlab.ui.control.UIAxes
        UIAxesTVsTemp                matlab.ui.control.UIAxes
        SuspensionTab                matlab.ui.container.Tab
        TiempoaMostrarSlider_2       matlab.ui.control.RangeSlider
        TiempoaMostrarSlider_2Label  matlab.ui.control.Label
        UIAxes3_4                    matlab.ui.control.UIAxes
        UIAxes3_3                    matlab.ui.control.UIAxes
        UIAxes3_2                    matlab.ui.control.UIAxes
        UIAxes3                      matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data = [];
    end
    
    methods (Access = private)
        
        % Lee el archivo CSV
        % Formato: Tiempo,Temperatura,RPM,Giroscopio Vert,Giroscopio Lat,Delantero Derecho,Delantero Isquierdo,Trasero Derecho,Trasero Isquierdo
        function data = openCSV(~) 
            [filename, pathname] = uigetfile('*.csv', 'Selecciona un archivo CSV');
            if ~isequal(filename, 0)
               fullpath = fullfile(pathname, filename);
               disp(['Archivo seleccionado: ', fullpath]);
               data = readmatrix(fullpath);
               
            else
                data = [];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ReproducionDropDown
        function ReproducionDropDownValueChanged(app, event)
            value = app.ReproducionDropDown.Value;
                        
            if(value == "CSV")
                app.COMLabel.Visible = "off";
                app.COMEditField.Visible = "off";
                app.ConnectSwitch.Visible = "off";

                %Se muestran los controles de CSV, funcionalidad se maneja en PlayButton
                app.TiempoaMostrarSlider.Visible = "on";
                app.TiempoaMostrarLabel.Visible = "on";
                app.PlayButton.Visible = "on";
                app.VelocidadSpinner.Visible = "on";
                app.VelocidadLabel.Visible = "on";
                app.StatusLamp.Color = "red";
                
                %Se abre el CSV y si se abre correctamente status green
                app.data = openCSV(app);
                if ~isequal(app.data, [])
                    app.StatusLamp.Color = "green";
                    %Actualiza el slider a nuevo rango
                    app.TiempoaMostrarSlider.Limits = ...
                        [app.data(1,1), app.data(end, 1)];
                end
                
            elseif(value == "Live")
                app.TiempoaMostrarSlider.Visible = "off";
                app.TiempoaMostrarLabel.Visible = "off";
                app.PlayButton.Visible = "off";
                app.VelocidadSpinner.Visible = "off";
                app.VelocidadLabel.Visible = "off";

                %Se muestran los controles de Live, funcionalidad se maneja en connectSwitch
                app.COMLabel.Visible = "on";
                app.COMEditField.Visible = "on";
                app.ConnectSwitch.Visible = "on";
                app.StatusLamp.Color = "red";

                
            end

        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            if ~isequal(app.data, [])
                
                % Index de fila en CSV donde empieza y termina la selecion del slider. 
                % Variables se usan para mostrar solo el rango deseado. 
                startIdx = find(round(app.data(:,1)) == ...
                    round(app.TiempoaMostrarSlider.Value(1)));
                endIdx = find(round(app.data(:,1)) == ...
                    round(app.TiempoaMostrarSlider.Value(2)));

                %Limpiar axes
                cla(app.UIAxesTVsTemp);
                cla(app.UIAxesTVsRpm);
                cla(app.UIAxesRpmVsTemp);
    
                %Se actualizan los valores con una pausa definida por VelocidadSpinner
                for i = startIdx : endIdx

                            app.TimeLabel.Text = append("t= ", num2str(app.data(i,1)), "s");
                            app.tempCGauge.Value = app.data(i,2);
                            app.rpmGauge.Value = app.data(i,3);
                            
                            plot(app.UIAxesTVsTemp, app.data(startIdx:i,1),app.data(startIdx:i,2), "-")
                            plot(app.UIAxesTVsRpm, app.data(startIdx:i,1),app.data(startIdx:i,3), "-")
                            plot(app.UIAxesRpmVsTemp,app.data(startIdx:i,3),app.data(startIdx:i,2), "-")
    
                            pause(1/(app.VelocidadSpinner.Value*10));
                end
            end
        end

        % Value changed function: ConnectSwitch
        function ConnectSwitchValueChanged(app, event)
            
            app.data = [];
            if app.ConnectSwitch.Value == "Connect"
                app.SaveDatatoCSVButton.Visible = "off";

                comPort = append('COM', num2str(app.COMEditField.Value));
                
                %Intenta conectarse al serial port. 
                %Si no se puede status amarillo.
                try
                    device = serialport(comPort, 9600);
                    app.StatusLamp.Color = "Green";
                catch e
                    app.StatusLamp.Color = "Yellow";
                end
    
                %Lee cada linea del SerialMonitor, actualiza en graficos y
                %añade los datos a app.data.
                while app.ConnectSwitch.Value == "Connect"
                    try
                        dataLine = readline(device);
                    catch e
                        break;
                    end
                    
                    %Append una nueva linea
                    app.data = [app.data; str2double(strsplit(dataLine, ','))];

                    %Para poder mostrar todos los datos cada iteracion 
                    % se vuelve a graficar todo data como una nueva grafica 
                    % en el axis, remplazando la anterior. 
                    app.tempCGauge.Value = app.data(end,2);
                    app.rpmGauge.Value = app.data(end,3);
                    plot(app.UIAxesTVsTemp, app.data(1:end,1),app.data(1:end,2), "-")
                    plot(app.UIAxesTVsRpm, app.data(1:end,1),app.data(1:end,3), "-")
                    plot(app.UIAxesRpmVsTemp,app.data(1:end,3),app.data(1:end,2), "-")
                end
                
            else
                app.StatusLamp.Color = "Red";
                app.SaveDatatoCSVButton.Visible = "on";
            end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TelemetriaBajaSAEUIFigure = uifigure('Visible', 'off');
            app.TelemetriaBajaSAEUIFigure.Name = 'Telemetria Baja SAE';
            app.TabGroup = uitabgroup(app.TelemetriaBajaSAEUIFigure);
            app.MotorTab = uitab(app.TabGroup);
            app.MotorTab.Title = 'Motor';
            app.UIAxesTVsTemp = uiaxes(app.MotorTab);
            title(app.UIAxesTVsTemp, 't(s) vs temp(C°)')
            xlabel(app.UIAxesTVsTemp, 't(s)')
            ylabel(app.UIAxesTVsTemp, 'temp(C°)')
            zlabel(app.UIAxesTVsTemp, 'Z')
            app.UIAxesTVsRpm = uiaxes(app.MotorTab);
            title(app.UIAxesTVsRpm, 't(s) vs rpm')
            xlabel(app.UIAxesTVsRpm, 't(s)')
            ylabel(app.UIAxesTVsRpm, 'rpm')
            zlabel(app.UIAxesTVsRpm, 'Z')
            app.UIAxesRpmVsTemp = uiaxes(app.MotorTab);
            title(app.UIAxesRpmVsTemp, 'rmp vs temp(C°)')
            xlabel(app.UIAxesRpmVsTemp, 'rpm')
            ylabel(app.UIAxesRpmVsTemp, 'temp(C°)')
            zlabel(app.UIAxesRpmVsTemp, 'Z')
            app.tempCGaugeLabel = uilabel(app.MotorTab);
            app.tempCGaugeLabel.Text = 'temp(C°)';
            app.tempCGauge = uigauge(app.MotorTab, 'linear');
            app.tempCGauge.Limits = [0 150];
            app.TiempoaMostrarSlider = uislider(app.MotorTab, 'range');
            app.TiempoaMostrarSlider.Visible = 'off';
            app.TiempoaMostrarLabel = uilabel(app.MotorTab);
            app.TiempoaMostrarLabel.Visible = 'off';
            app.TiempoaMostrarLabel.Text = 'Tiempo a Mostrar';
            app.rpmx1000GaugeLabel = uilabel(app.MotorTab);
            app.rpmx1000GaugeLabel.Text = 'rpm x1000';
            app.rpmGauge = uigauge(app.MotorTab, 'semicircular');
            app.rpmGauge.Limits = [0 10];
            app.PlayButton = uibutton(app.MotorTab, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Visible = 'off';
            app.PlayButton.Text = 'Play';
            app.TimeLabel = uilabel(app.MotorTab);
            app.TimeLabel.Text = '';
            app.VelocidadSpinner = uispinner(app.MotorTab);
            app.VelocidadSpinner.Limits = [0 Inf];
            app.VelocidadSpinner.Visible = 'off';
            app.VelocidadSpinner.Value = 1;
            app.VelocidadLabel = uilabel(app.MotorTab);
            app.VelocidadLabel.Visible = 'off';
            app.VelocidadLabel.Text = 'Velocidad';
            app.COMEditField = uieditfield(app.MotorTab, 'numeric');
            app.COMEditField.Limits = [1 Inf];
            app.COMEditField.ValueDisplayFormat = '%.0f';
            app.COMEditField.Visible = 'off';
            app.COMEditField.Value = 1;
            app.COMLabel = uilabel(app.MotorTab);
            app.COMLabel.Visible = 'off';
            app.COMLabel.Text = 'COM';
            app.ConnectSwitch = uiswitch(app.MotorTab, 'slider');
            app.ConnectSwitch.Items = {'Disconnect', 'Connect'};
            app.ConnectSwitch.ValueChangedFcn = createCallbackFcn(app, @ConnectSwitchValueChanged, true);
            app.ConnectSwitch.Visible = 'off';
            app.ConnectSwitch.Value = 'Disconnect';
            app.SaveDatatoCSVButton = uibutton(app.MotorTab, 'push');
            app.SaveDatatoCSVButton.Visible = 'off';
            app.SaveDatatoCSVButton.Text = 'Save Data to CSV';
            app.SuspensionTab = uitab(app.TabGroup);
            app.SuspensionTab.Title = 'Suspension';
            app.UIAxes2 = uiaxes(app.SuspensionTab);
            title(app.UIAxes2, 'Giroscopio')
            xlabel(app.UIAxes2, 'Gs Lateral')
            ylabel(app.UIAxes2, 'Gs Vertical')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.SuspensionTab);
            title(app.UIAxes3, 'Derecho Delantero')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes3_2 = uiaxes(app.SuspensionTab);
            title(app.UIAxes3_2, 'Isquierdo Delantero')
            xlabel(app.UIAxes3_2, 'X')
            ylabel(app.UIAxes3_2, 'Y')
            zlabel(app.UIAxes3_2, 'Z')
            app.UIAxes3_3 = uiaxes(app.SuspensionTab);
            title(app.UIAxes3_3, 'Derecho Trasero')
            xlabel(app.UIAxes3_3, 'X')
            ylabel(app.UIAxes3_3, 'Y')
            zlabel(app.UIAxes3_3, 'Z')
            app.UIAxes3_4 = uiaxes(app.SuspensionTab);
            title(app.UIAxes3_4, 'Isquierdo Trasero')
            xlabel(app.UIAxes3_4, 'X')
            ylabel(app.UIAxes3_4, 'Y')
            zlabel(app.UIAxes3_4, 'Z')
            app.TiempoaMostrarSlider_2Label = uilabel(app.SuspensionTab);
            app.TiempoaMostrarSlider_2Label.Text = {'Tiempo a '; 'Mostrar'};
            app.TiempoaMostrarSlider_2 = uislider(app.SuspensionTab, 'range');
            app.ReproducionDropDownLabel = uilabel(app.TelemetriaBajaSAEUIFigure);
            app.ReproducionDropDownLabel.Text = 'Reproducion';
            app.ReproducionDropDown = uidropdown(app.TelemetriaBajaSAEUIFigure);
            app.ReproducionDropDown.Items = {'(Elegir)', 'Live', 'CSV'};
            app.ReproducionDropDown.ValueChangedFcn = createCallbackFcn(app, @ReproducionDropDownValueChanged, true);
            app.ReproducionDropDown.Value = '(Elegir)';
            app.LampLabel = uilabel(app.TelemetriaBajaSAEUIFigure);
            app.LampLabel.Text = '';
            app.StatusLamp = uilamp(app.TelemetriaBajaSAEUIFigure);
            % Show the figure after all components are created
            app.TelemetriaBajaSAEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TelemetriaBajaSae

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TelemetriaBajaSAEUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TelemetriaBajaSAEUIFigure)
        end
    end
end