classdef TelemetriaBajaSae < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        TelemetriaBajaSAEUIFigure  matlab.ui.Figure
        AyudaButton                matlab.ui.control.Button
        TimeLabel                  matlab.ui.control.Label
        VelocidadSpinner           matlab.ui.control.Spinner
        VelocidadLabel             matlab.ui.control.Label
        ReproducirButton           matlab.ui.control.Button
        TiempoaMostrarSlider       matlab.ui.control.RangeSlider
        TiempoaMostrarLabel        matlab.ui.control.Label
        GuardaraCSVButton          matlab.ui.control.Button
        ConectarSwitch             matlab.ui.control.Switch
        COMEditField               matlab.ui.control.NumericEditField
        COMLabel                   matlab.ui.control.Label
        StatusLamp                 matlab.ui.control.Lamp
        LampLabel                  matlab.ui.control.Label
        ReproducionDropDown        matlab.ui.control.DropDown
        ReproducionDropDownLabel   matlab.ui.control.Label
        TabGroup                   matlab.ui.container.TabGroup
        MotorTab                   matlab.ui.container.Tab
        rpmGauge                   matlab.ui.control.SemicircularGauge
        rpmx1000GaugeLabel         matlab.ui.control.Label
        tempCGauge                 matlab.ui.control.LinearGauge
        tempCGaugeLabel            matlab.ui.control.Label
        UIAxesRpmVsTemp            matlab.ui.control.UIAxes
        UIAxesTVsRpm               matlab.ui.control.UIAxes
        UIAxesTVsTemp              matlab.ui.control.UIAxes
        SuspensionTab              matlab.ui.container.Tab
        UIAxesIsquierdoTrasero     matlab.ui.control.UIAxes
        UIAxesDerechoTrasero       matlab.ui.control.UIAxes
        UIAxesIsquierdoDelantero   matlab.ui.control.UIAxes
        UIAxesDerechoDelantero     matlab.ui.control.UIAxes
        UIAxesGiroscopio           matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        data = [];
    end
    
    methods (Access = private)
        
        % Lee el archivo CSV
        % Formato: Tiempo,Temperatura,RPM,Giroscopio Vert,Giroscopio Lat,Delantero Derecho,Delantero Isquierdo,Trasero Derecho,Trasero Isquierdo
        function data = openCSV(~) 
            [filename, pathname] = uigetfile('*.csv', 'Selecciona un archivo CSV');
            if ~isequal(filename, 0)
               fullpath = fullfile(pathname, filename);
               data = readmatrix(fullpath);
               
            else
                data = [];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: ReproducionDropDown
        function ReproducionDropDownValueChanged(app, event)
            value = app.ReproducionDropDown.Value;
                        
            if(value == "CSV")
                app.COMLabel.Visible = "off";
                app.COMEditField.Visible = "off";
                app.ConectarSwitch.Visible = "off";
                
                %Se abre el CSV y si se abre correctamente status green
                app.data = openCSV(app);
                [dataHeight, dataLength] = size(app.data);

                if dataLength == 9 && dataHeight > 0
                    %Se muestran los controles de CSV, funcionalidad se maneja en PlayButton
                    app.TimeLabel.Visible = "on";
                    app.TiempoaMostrarSlider.Visible = "on";
                    app.TiempoaMostrarLabel.Visible = "on";
                    app.ReproducirButton.Visible = "on";
                    app.VelocidadSpinner.Visible = "on";
                    app.VelocidadLabel.Visible = "on";
                    app.StatusLamp.Color = "red";
                    app.StatusLamp.Color = "green";
                    %Actualiza el slider a nuevo rango
                    app.TiempoaMostrarSlider.Limits = ...
                        [app.data(1,1), app.data(end, 1)];
                else 
                    app.StatusLamp.Color = "yellow";
                end
                
            elseif(value == "Live")
                app.TimeLabel.Visible = "off";
                app.TiempoaMostrarSlider.Visible = "off";
                app.TiempoaMostrarLabel.Visible = "off";
                app.ReproducirButton.Visible = "off";
                app.VelocidadSpinner.Visible = "off";
                app.VelocidadLabel.Visible = "off";

                %Se muestran los controles de Live, funcionalidad se maneja en connectSwitch
                app.COMLabel.Visible = "on";
                app.COMEditField.Visible = "on";
                app.ConectarSwitch.Visible = "on";
                app.StatusLamp.Color = "red";

                
            end

        end

        % Button pushed function: ReproducirButton
        function ReproducirButtonPushed(app, event)
             if ~isequal(app.data, [])
                
                % Index de fila en CSV donde empieza y termina la selecion del slider. 
                % Variables se usan para mostrar solo el rango deseado. 
                startIdx = find(round(app.data(:,1)) == ...
                    round(app.TiempoaMostrarSlider.Value(1)));
                endIdx = find(round(app.data(:,1)) == ...
                    round(app.TiempoaMostrarSlider.Value(2)));

                %Limpiar axes
                cla(app.UIAxesTVsTemp);
                cla(app.UIAxesTVsRpm);
                cla(app.UIAxesRpmVsTemp);

                cla(app.UIAxesGiroscopio);
                cla(app.UIAxesDerechoDelantero);
                cla(app.UIAxesIsquierdoDelantero);
                cla(app.UIAxesDerechoTrasero);
                cla(app.UIAxesIsquierdoTrasero)
    
                %Se actualizan los valores con una pausa definida por VelocidadSpinner
                %Se usa (1) Y (end) por que aveces puede haber multiples instancias en las que el valor redondeado del slider y los valores redondeados de app.data pueden ser iguales cuando se toman varias muestras en un segundo. 
                for i = startIdx(1) : endIdx(end)  
                            %Gauges
                            app.TimeLabel.Text = append("t= ", num2str(app.data(i,1)), "s");
                            app.tempCGauge.Value = app.data(i,2);
                            app.rpmGauge.Value = app.data(i,3);
                            
                            %Motor Axis
                            plot(app.UIAxesTVsTemp, app.data(startIdx:i,1),app.data(startIdx:i,2), "-")
                            plot(app.UIAxesTVsRpm, app.data(startIdx:i,1),app.data(startIdx:i,3), "-")
                            plot(app.UIAxesRpmVsTemp,app.data(startIdx:i,3),app.data(startIdx:i,2), "-")
                            
                            %Suspension
                            plot(app.UIAxesGiroscopio, app.data(startIdx:i,4),app.data(startIdx:i,5), "-")
                            plot(app.UIAxesDerechoDelantero, app.data(startIdx:i,1),app.data(startIdx:i,6), "-")
                            plot(app.UIAxesIsquierdoDelantero,app.data(startIdx:i,1),app.data(startIdx:i,7), "-")
                            plot(app.UIAxesDerechoTrasero,app.data(startIdx:i,1),app.data(startIdx:i,8), "-")
                            plot(app.UIAxesIsquierdoTrasero,app.data(startIdx:i,1),app.data(startIdx:i,9), "-")

                            pause(1/(app.VelocidadSpinner.Value*10));
                end
            end
        end

        % Value changed function: ConectarSwitch
        function ConectarSwitchValueChanged(app, event)
            if app.ConectarSwitch.Value == "Connect"
                app.data = [];
                app.GuardaraCSVButton.Visible = "off";

                comPort = append('COM', num2str(app.COMEditField.Value));
                
                %Intenta conectarse al serial port. 
                %Si no se puede status amarillo.
                try
                    device = serialport(comPort, 9600);
                    app.StatusLamp.Color = "Green";
                catch e
                    app.StatusLamp.Color = "Yellow";
                end
    
                %Lee cada linea del SerialMonitor, actualiza en graficos y
                %añade los datos a app.data.
                while app.ConectarSwitch.Value == "Connect"
                    try
                        dataLine = readline(device);
                        % encontrar alguna manera de intermitentemente
                        % flush el serial sin romper las lineas. 
                    catch e
                        break;
                    end
                    
                    %Append una nueva linea
                    app.data = [app.data; str2double(strsplit(dataLine, ','))];

                    %Para poder mostrar todos los datos cada iteracion 
                    % se vuelve a graficar todo data como una nueva grafica 
                    % en el axis, remplazando la anterior. 
                    
                    %Gauges
                    app.tempCGauge.Value = app.data(end,2);
                    app.rpmGauge.Value = app.data(end,3);

                    %Motor
                    plot(app.UIAxesTVsTemp, app.data(1:end,1),app.data(1:end,2), "-")
                    plot(app.UIAxesTVsRpm, app.data(1:end,1),app.data(1:end,3), "-")
                    plot(app.UIAxesRpmVsTemp,app.data(1:end,3),app.data(1:end,2), "-")

                    %Suspension
                    plot(app.UIAxesGiroscopio, app.data(1:end,4),app.data(1:end,5), "-")
                    plot(app.UIAxesDerechoDelantero, app.data(1:end,1),app.data(1:end,6), "-")
                    plot(app.UIAxesIsquierdoDelantero,app.data(1:end,1),app.data(1:end,7), "-")
                    plot(app.UIAxesDerechoTrasero,app.data(1:end,1),app.data(1:end,8), "-")
                    plot(app.UIAxesIsquierdoTrasero,app.data(1:end,1),app.data(1:end,9), "-")
                end
                
            else
                app.StatusLamp.Color = "Red";
                app.GuardaraCSVButton.Visible = "on";
            end
            
        end

        % Button pushed function: GuardaraCSVButton
        function GuardaraCSVButtonPushed(app, event)

            currentDateTime = datetime('now', 'Format', 'dd.MM.yy_HH.mm.ss');
            
            fileName = ['Datos_' char(currentDateTime) '.csv'];
            
            [fileName, fileLocation] = uiputfile({'*.csv', 'Archivos CSV (*.csv)'}, ...
        'Guardar Archivo', fileName);
            
            filePath = fullfile(fileLocation, fileName);

            app.data = [ "Tiempo","Temperatura", ...
                "RPM","Giroscopio Vert","Giroscopio Lat", ...
                "Derecho Delantero","Isquierdo Delantero", ...
                "Derecho Trasero","Isquierdo Trasero"; ...
                app.data];
            
            writematrix(app.data,filePath);
            
        end

        % Button pushed function: AyudaButton
        function AyudaButtonPushed(app, event)
            web('Manual de Usuario TelemetryViewer.pdf', '-browser');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.TelemetriaBajaSAEUIFigure = uifigure('Visible', 'off');
            app.TelemetriaBajaSAEUIFigure.Name = 'Telemetria Baja SAE';
            app.TabGroup = uitabgroup(app.TelemetriaBajaSAEUIFigure);
            app.MotorTab = uitab(app.TabGroup);
            app.MotorTab.Title = 'Motor';
            app.UIAxesTVsTemp = uiaxes(app.MotorTab);
            title(app.UIAxesTVsTemp, 't(s) vs temp(C°)')
            xlabel(app.UIAxesTVsTemp, 't(s)')
            ylabel(app.UIAxesTVsTemp, 'temp(C°)')
            zlabel(app.UIAxesTVsTemp, 'Z')
            app.UIAxesTVsRpm = uiaxes(app.MotorTab);
            title(app.UIAxesTVsRpm, 't(s) vs rpm')
            xlabel(app.UIAxesTVsRpm, 't(s)')
            ylabel(app.UIAxesTVsRpm, 'rpm')
            zlabel(app.UIAxesTVsRpm, 'Z')
            app.UIAxesRpmVsTemp = uiaxes(app.MotorTab);
            title(app.UIAxesRpmVsTemp, 'rmp vs temp(C°)')
            xlabel(app.UIAxesRpmVsTemp, 'rpm')
            ylabel(app.UIAxesRpmVsTemp, 'temp(C°)')
            zlabel(app.UIAxesRpmVsTemp, 'Z')
            app.tempCGaugeLabel = uilabel(app.MotorTab);
            app.tempCGaugeLabel.Text = 'temp(C°)';
            app.tempCGauge = uigauge(app.MotorTab, 'linear');
            app.tempCGauge.Limits = [0 150];
            app.rpmx1000GaugeLabel = uilabel(app.MotorTab);
            app.rpmx1000GaugeLabel.Text = 'rpm x1000';
            app.rpmGauge = uigauge(app.MotorTab, 'semicircular');
            app.rpmGauge.Limits = [0 10];
            app.SuspensionTab = uitab(app.TabGroup);
            app.SuspensionTab.Title = 'Suspension';
            app.UIAxesGiroscopio = uiaxes(app.SuspensionTab);
            title(app.UIAxesGiroscopio, 'Giroscopio')
            xlabel(app.UIAxesGiroscopio, 'Eje Horizontal (°)')
            ylabel(app.UIAxesGiroscopio, 'Eje Sagital (°)')
            zlabel(app.UIAxesGiroscopio, 'Z')
            app.UIAxesDerechoDelantero = uiaxes(app.SuspensionTab);
            title(app.UIAxesDerechoDelantero, 'Derecho Delantero')
            xlabel(app.UIAxesDerechoDelantero, 't(s)')
            ylabel(app.UIAxesDerechoDelantero, 'Y')
            zlabel(app.UIAxesDerechoDelantero, 'Z')
            app.UIAxesIsquierdoDelantero = uiaxes(app.SuspensionTab);
            title(app.UIAxesIsquierdoDelantero, 'Isquierdo Delantero')
            xlabel(app.UIAxesIsquierdoDelantero, 't(s)')
            ylabel(app.UIAxesIsquierdoDelantero, 'Y')
            zlabel(app.UIAxesIsquierdoDelantero, 'Z')
            app.UIAxesDerechoTrasero = uiaxes(app.SuspensionTab);
            title(app.UIAxesDerechoTrasero, 'Derecho Trasero')
            xlabel(app.UIAxesDerechoTrasero, 't(s)')
            ylabel(app.UIAxesDerechoTrasero, 'Y')
            zlabel(app.UIAxesDerechoTrasero, 'Z')
            app.UIAxesIsquierdoTrasero = uiaxes(app.SuspensionTab);
            title(app.UIAxesIsquierdoTrasero, 'Isquierdo Trasero')
            xlabel(app.UIAxesIsquierdoTrasero, 't(s)')
            ylabel(app.UIAxesIsquierdoTrasero, 'Y')
            zlabel(app.UIAxesIsquierdoTrasero, 'Z')
            app.ReproducionDropDownLabel = uilabel(app.TelemetriaBajaSAEUIFigure);
            app.ReproducionDropDownLabel.Text = 'Reproducion';
            app.ReproducionDropDown = uidropdown(app.TelemetriaBajaSAEUIFigure);
            app.ReproducionDropDown.Items = {'(Elegir)', 'Live', 'CSV'};
            app.ReproducionDropDown.ValueChangedFcn = createCallbackFcn(app, @ReproducionDropDownValueChanged, true);
            app.ReproducionDropDown.Value = '(Elegir)';
            app.LampLabel = uilabel(app.TelemetriaBajaSAEUIFigure);
            app.LampLabel.Text = '';
            app.StatusLamp = uilamp(app.TelemetriaBajaSAEUIFigure);
            app.COMLabel = uilabel(app.TelemetriaBajaSAEUIFigure);
            app.COMLabel.Visible = 'off';
            app.COMLabel.Text = 'COM';
            app.COMEditField = uieditfield(app.TelemetriaBajaSAEUIFigure, 'numeric');
            app.COMEditField.Limits = [1 Inf];
            app.COMEditField.ValueDisplayFormat = '%.0f';
            app.COMEditField.Visible = 'off';
            app.COMEditField.Value = 1;
            app.ConectarSwitch = uiswitch(app.TelemetriaBajaSAEUIFigure, 'slider');
            app.ConectarSwitch.Items = {'Desconectar', 'Conectar'};
            app.ConectarSwitch.ValueChangedFcn = createCallbackFcn(app, @ConectarSwitchValueChanged, true);
            app.ConectarSwitch.Visible = 'off';
            app.ConectarSwitch.Value = 'Desconectar';
            app.GuardaraCSVButton = uibutton(app.TelemetriaBajaSAEUIFigure, 'push');
            app.GuardaraCSVButton.ButtonPushedFcn = createCallbackFcn(app, @GuardaraCSVButtonPushed, true);
            app.GuardaraCSVButton.Visible = 'off';
            app.GuardaraCSVButton.Text = 'Guardar a CSV';
            app.TiempoaMostrarLabel = uilabel(app.TelemetriaBajaSAEUIFigure);
            app.TiempoaMostrarLabel.Visible = 'off';
            app.TiempoaMostrarLabel.Text = 'Tiempo a Mostrar';
            app.TiempoaMostrarSlider = uislider(app.TelemetriaBajaSAEUIFigure, 'range');
            app.TiempoaMostrarSlider.Visible = 'off';
            app.ReproducirButton = uibutton(app.TelemetriaBajaSAEUIFigure, 'push');
            app.ReproducirButton.ButtonPushedFcn = createCallbackFcn(app, @ReproducirButtonPushed, true);
            app.ReproducirButton.Visible = 'off';
            app.ReproducirButton.Text = 'Reproducir';
            app.VelocidadLabel = uilabel(app.TelemetriaBajaSAEUIFigure);
            app.VelocidadLabel.Visible = 'off';
            app.VelocidadLabel.Text = 'Velocidad';
            app.VelocidadSpinner = uispinner(app.TelemetriaBajaSAEUIFigure);
            app.VelocidadSpinner.Limits = [0 Inf];
            app.VelocidadSpinner.Visible = 'off';
            app.VelocidadSpinner.Value = 1;
            app.TimeLabel = uilabel(app.TelemetriaBajaSAEUIFigure);
            app.TimeLabel.Text = '';
            app.AyudaButton = uibutton(app.TelemetriaBajaSAEUIFigure, 'push');
            app.AyudaButton.ButtonPushedFcn = createCallbackFcn(app, @AyudaButtonPushed, true);
            app.AyudaButton.Text = 'Ayuda';
            % Show the figure after all components are created
            app.TelemetriaBajaSAEUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TelemetriaBajaSae

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.TelemetriaBajaSAEUIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.TelemetriaBajaSAEUIFigure)
        end
    end
end